classdef music_synthesis_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        RightPanel      matlab.ui.container.Panel
        wavLabel        matlab.ui.control.Label
        Label_6         matlab.ui.control.Label
        play            matlab.ui.control.Button
        save            matlab.ui.control.Button
        save_address    matlab.ui.control.EditField
        clips           matlab.ui.control.TextArea
        LeftPanel       matlab.ui.container.Panel
        Label_7         matlab.ui.control.Label
        v_01Label       matlab.ui.control.Label
        MatlabLabel     matlab.ui.control.Label
        instrument      matlab.ui.control.DropDown
        Label_5         matlab.ui.control.Label
        base_frequency  matlab.ui.control.EditField
        Label_4         matlab.ui.control.Label
        beat_tempo      matlab.ui.control.EditField
        Label_3         matlab.ui.control.Label
        sample_rate     matlab.ui.control.NumericEditField
        Label           matlab.ui.control.Label
        speed           matlab.ui.control.Slider
    end

    
    properties (Access = private)
        music % 主对象
        instruction = 'piano' % 乐器
        address = '东方红_音乐合成.wav' % 保存地址
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.music = music_synthesis( ...
                sample_rate = 8000, ...
                speed = 'Moderato', ...
                beat_tempo = '2/4', ...
                base_frequency = 'f1', ...
                envelope_description = music_const.envelope_piano, ...
                harmonic = music_const.harmonic_piano, ...
                clips_description = [
                    '5 _5 _6 ' ...
                    '2 - ' ...
                    '1 _1 _b6 ' ...
                    '2 -'
                    ] ...
                );
        end

        % Value changed function: base_frequency
        function base_frequencyValueChanged(app, event)
            value = app.base_frequency.Value;
            app.music.base_frequency = value;
        end

        % Value changed function: beat_tempo
        function beat_tempoValueChanged(app, event)
            value = app.beat_tempo.Value;
            app.music.beat_tempo = value;
        end

        % Button pushed function: play
        function playButtonPushed(app, event)
            sound(app.music.clips);
        end

        % Value changed function: save_address
        function save_addressValueChanged(app, event)
            value = app.save_address.Value;
            app.address = [value, '.wav'];
        end

        % Button pushed function: save
        function saveButtonPushed(app, event)
            audiowrite(app.address, app.music.clips, app.music.sample_rate);
        end

        % Value changed function: clips
        function clipsValueChanged(app, event)
            value = app.clips.Value;
            app.music.clips_description = [];
            for i = 1:length(value)
                app.music.clips_description = [app.music.clips_description, ' ', value{i}];
            end
        end

        % Value changed function: instrument
        function instrumentValueChanged(app, event)
            value = app.instrument.Value;
            if strcmp(value, 'piano')
                app.music.envelope_description = music_const.envelope_piano;
                app.music.harmonic = music_const.harmonic_piano;
            elseif strcmp(value, 'guitar')
                app.music.envelope_description = music_const.envelope_guitar;
                app.music.harmonic = music_const.harmonic_guitar;
            elseif strcmp(value, 'shakuhachi')
                app.music.envelope_description = music_const.envelope_shakuhachi;
                app.music.harmonic = music_const.harmonic_shakuhachi;
            end
        end

        % Value changed function: speed
        function speedValueChanged(app, event)
            value = app.speed.Value;
            app.music.speed = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.speed = uislider(app.LeftPanel);
            app.speed.Limits = [40 200];
            app.speed.MajorTicks = [52 88 132 184];
            app.speed.MajorTickLabels = {'Lento', 'Moderato', 'Allegro', 'Presto'};
            app.speed.ValueChangedFcn = createCallbackFcn(app, @speedValueChanged, true);
            app.speed.MinorTicks = [];
            app.speed.Value = 88;
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '采样率';
            app.sample_rate = uieditfield(app.LeftPanel, 'numeric');
            app.sample_rate.Limits = [1 Inf];
            app.sample_rate.Value = 8000;
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '节    拍';
            app.beat_tempo = uieditfield(app.LeftPanel, 'text');
            app.beat_tempo.ValueChangedFcn = createCallbackFcn(app, @beat_tempoValueChanged, true);
            app.beat_tempo.Tooltip = {'2/4, 4/4 ...'};
            app.beat_tempo.Value = '2/4';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '基    调';
            app.base_frequency = uieditfield(app.LeftPanel, 'text');
            app.base_frequency.ValueChangedFcn = createCallbackFcn(app, @base_frequencyValueChanged, true);
            app.base_frequency.Tooltip = {'c1, #c1, bc1 ...'};
            app.base_frequency.Value = 'f1';
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = '乐    器';
            app.instrument = uidropdown(app.LeftPanel);
            app.instrument.Items = {'钢琴', '吉他', '笛子'};
            app.instrument.ItemsData = {'piano', 'guitar', 'shakuhachi'};
            app.instrument.ValueChangedFcn = createCallbackFcn(app, @instrumentValueChanged, true);
            app.instrument.Value = 'piano';
            app.MatlabLabel = uilabel(app.LeftPanel);
            app.MatlabLabel.Text = 'Matlab 音乐合成';
            app.v_01Label = uilabel(app.LeftPanel);
            app.v_01Label.Text = 'v_0.1';
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '速    度';
            app.RightPanel = uipanel(app.UIFigure);
            app.clips = uitextarea(app.RightPanel);
            app.clips.ValueChangedFcn = createCallbackFcn(app, @clipsValueChanged, true);
            app.clips.Tooltip = {'1 : 1 拍'; '_1 : 1/2 拍'; '__1 : 1/4 拍'; '___1 : 1/8 拍'; '1 - : 2 拍'; '1 - - : 3 拍'; '1 - - - : 4 拍'; '1 . : 1.5 拍'; ''; '#1 _#1 升调'; 'b1 _b1 降调'};
            app.clips.Value = {'5 _5 _6'; '2 -'; '1 _1 _b6'; '2 -'};
            app.save_address = uieditfield(app.RightPanel, 'text');
            app.save_address.ValueChangedFcn = createCallbackFcn(app, @save_addressValueChanged, true);
            app.save_address.Value = '东方红_音乐合成';
            app.save = uibutton(app.RightPanel, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.save.Text = '保存';
            app.play = uibutton(app.RightPanel, 'push');
            app.play.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.play.Text = '播放';
            app.Label_6 = uilabel(app.RightPanel);
            app.Label_6.Text = '保存文件：';
            app.wavLabel = uilabel(app.RightPanel);
            app.wavLabel.Text = '.wav';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = music_synthesis_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end